classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        RunCSPButton  matlab.ui.control.Button
        Slider        matlab.ui.control.Slider
        SliderLabel   matlab.ui.control.Label
        UIAxes2       matlab.ui.control.UIAxes
        UIAxes        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        EEG % Description
        FS
        TS
        recName
    end
    
    methods (Access = private)
        function [T0, T1, T2] = getIntervals(app, recName,annotator,fs,sigLen)
            T0=[];
            T1=[];
            T2=[];
            [annot, ~, ~, ~, ~, cmt] = rdann(recName, annotator);
            cmt = string(cmt);
            for i=1:size(annot,1)
                splt=split(cmt(i), ' ');
                dur = str2double(splt(3))*fs;
                intEnd = annot(i) + dur-1;
                if (i<length(annot))
                    intEnd = min(annot(i+1)-1, intEnd);
                else
                    intEnd = min(sigLen, intEnd);
                end
                    
                if (splt(1)=="T0")
                    T0 = [T0; [annot(i) intEnd]];
                elseif (splt(1)=="T1")
                    T1 = [T1; annot(i) intEnd];
                elseif (splt(1)=="T2")
                    T2 = [T2; annot(i) intEnd];
                end
            end
        end
        
        function [result] = f_CSP(app, cdat1, cdat2)
            r0 = (cdat1 * cdat1')/trace(cdat1 * cdat1');
            r1 = (cdat2 * cdat2')/trace(cdat2 * cdat2');
            Rsum = r0 + r1;
            
            [EVecsum,EValsum] = eig(Rsum);
            [EValsum,ind] = sort(diag(EValsum),'descend');
            EVecsum = EVecsum(:,ind);
    
            W = sqrt(inv(diag(EValsum))) * EVecsum';
            
            s0 = W * r0 * W';
            s1 = W * r1 * W';
            
            [B,D] = eig(s0, s1);    
            [D,ind]=sort(diag(D));B=B(:,ind);
            result = B'*W;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.recName = 'eegmmidb/S001/S001R03.edf';
            [ eeg, fs, ts] = rdsamp(app.recName, 1:64);
            eeg = transpose(eeg);
            app.EEG = eeg;
            app.FS = fs;
            app.TS = ts;
            
            plot(app.UIAxes, eeg(1, :))
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = int32(event.Value);
            plot(app.UIAxes, app.EEG(changingValue, :))
            
            app.UIAxes.Title.String = strcat("EEG Signal Preview Channel ", int2str(changingValue));
        end

        % Button pushed function: RunCSPButton
        function RunCSPButtonPushed(app, event)
            [T0, T1, T2] = app.getIntervals(app.recName, 'event', app.FS, size(app.EEG, 2));
            
            c1dat0 = app.EEG(:, T1(1,1):T1(1,2));
            c2dat0 = app.EEG(:, T2(1,1):T2(1,2));

            c1dat1 = app.EEG(:, T1(size(T1,1),1):T1(size(T1,1),2));
            c2dat1 = app.EEG(:, T2(size(T2,1),1):T2(size(T2,1),2));
            
            W = app.f_CSP(c1dat0, c2dat0);
            
            S11 = W*c1dat1;
            S21 = W*c2dat1;

            S11A = [S11(1, :).', S11(size(S11,1),:).'].';
            S21A = [S21(1, :).', S21(size(S21,1),:).'].';
            
            plot(app.UIAxes2, S11A(1,:), S11A(2,:), 'o');
            hold(app.UIAxes2, 'on')
            plot(app.UIAxes2, S21A(1,:), S21A(2,:), 'x');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'EEG SIgnal Preview')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'CSP')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [1 64];
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.Value = 1;
            app.RunCSPButton = uibutton(app.UIFigure, 'push');
            app.RunCSPButton.ButtonPushedFcn = createCallbackFcn(app, @RunCSPButtonPushed, true);
            app.RunCSPButton.Text = 'Run CSP';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end